classdef ByrdApp < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    UIFigure                     matlab.ui.Figure
    VisualizationPanel           matlab.ui.container.Panel
    DataSelectionLabel           matlab.ui.control.Label
    MarkerSizeLabel              matlab.ui.control.Label
    MarkerSizeSpinner            matlab.ui.control.Spinner
    MarkerTypeLabel              matlab.ui.control.Label
    ScatterShapeDropDown         matlab.ui.control.DropDown
    DataVisualizationPanelLabel  matlab.ui.control.Label
    MoreCustomizationsPanel      matlab.ui.container.Panel
    DisplayTrendCheckBox         matlab.ui.control.CheckBox
    LinearRegressionCheckBox     matlab.ui.control.CheckBox
    MovingAverageSlider          matlab.ui.control.Slider
    MovingAverageSliderLabel     matlab.ui.control.Label
    DataLabel                    matlab.ui.control.Label
    FontStyleDropDown            matlab.ui.control.DropDown
    FontStyleDropDownLabel       matlab.ui.control.Label
    FontSizeSpinner              matlab.ui.control.Spinner
    FontSizeSpinnerLabel         matlab.ui.control.Label
    AxesLabel                    matlab.ui.control.Label
    YMinorGridCheckBox           matlab.ui.control.CheckBox
    XMinorGridCheckBox           matlab.ui.control.CheckBox
    YGridCheckBox                matlab.ui.control.CheckBox
    XGridCheckBox                matlab.ui.control.CheckBox
    LogYScaleCheckBox            matlab.ui.control.CheckBox
    LogXScaleCheckBox            matlab.ui.control.CheckBox
    FontRadioButtonGroup         matlab.ui.container.ButtonGroup
    FontLabel                    matlab.ui.control.Label
    SerifButton                  matlab.ui.control.RadioButton
    SansSerifButton              matlab.ui.control.RadioButton
    LineWidthLabel               matlab.ui.control.Label
    LineStyleLabel               matlab.ui.control.Label
    ColorLabel                   matlab.ui.control.Label
    LineStyleDropDown            matlab.ui.control.DropDown
    LineWidthSpinner             matlab.ui.control.Spinner
    ColorDropDown                matlab.ui.control.DropDown
    CustomizeDropDownLabel       matlab.ui.control.Label
    ChartTypeLabel               matlab.ui.control.Label
    YDataLabel                   matlab.ui.control.Label
    XDataLabel                   matlab.ui.control.Label
    SaveFigureButton             matlab.ui.control.Button
    ResetButton                  matlab.ui.control.Button
    PlotButton                   matlab.ui.control.Button
    ChartTypeDropDown            matlab.ui.control.DropDown
    YDataDropDown                matlab.ui.control.DropDown
    XDataDropDown                matlab.ui.control.DropDown
    ParametersDropDownLabel      matlab.ui.control.Label
    DataAxes                     matlab.ui.control.UIAxes
    ByrdDataImportToolPanel      matlab.ui.container.Panel
    DataTableLabel               matlab.ui.control.Label
    SaveTableButton              matlab.ui.control.Button
    DataTable                    matlab.ui.control.Table
    ImportButton                 matlab.ui.control.Button
    CitationTextArea             matlab.ui.control.TextArea
    CitationTextAreaLabel        matlab.ui.control.Label
    DataSetDropDown              matlab.ui.control.DropDown
    DataSetDropDownLabel         matlab.ui.control.Label
    PublicationDropDown          matlab.ui.control.DropDown
    PublicationDropDownLabel     matlab.ui.control.Label
    IceCoreDropDown              matlab.ui.control.DropDown
    IceCoreDropDownLabel         matlab.ui.control.Label
    MapAxes                      matlab.ui.container.Panel
  end

  
  properties (Access = private)
    map_axes 
    RobinsonProjectedCRS 
    MercatorProjectedCRS 
    OrthographicProjectedCRS
    gs % Scatter point handle

    publications % Table of publications, columns = ice core sites
    ice_cores % Cell vector of ice core names
    selected_ice_core % Selected ice core
    selected_publication % Selected publcation
    selected_data_set % Selected data set
    datafilename % Name of selected data file
    sheetname % Name of selected data sheet
    citation % Citation string

    data % Imported data

    plt % The chart handle
    x % original x-data 
    y % original y-data
    y_avg % moving average y-data

    lin_reg_line % handle to linear regression line
    lin_reg_coef % linear regression coefficients
    trend_text   % handle to display trend text
  end
  

  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function startupFcn(app)
      clc
      %
      % Disable plotting features until import button is pressed
      %
      app.SaveTableButton.Enable = 'off';
      app.PlotButton.Enable = 'off';
      app.ResetButton.Enable = 'off';
      app.SaveFigureButton.Enable = 'off';
      app.MovingAverageSlider.MinorTicks=[];
      app.MovingAverageSlider.Enable = 'off';
      app.LinearRegressionCheckBox.Enable = 'off';
      app.DisplayTrendCheckBox.Enable = 'off';
      %
      % Ice core names dropdown
      %
      infofile='byrd-data-info.xlsx';
      warning off
      app.publications=readtable(infofile,...
        'VariableNamingRule','preserve');
      warning on
      ice_core_names=app.publications.Properties.VariableNames;
      app.ice_cores = ice_core_names;
      app.IceCoreDropDown.Items = app.ice_cores;
      app.selected_ice_core = app.ice_cores{1};
      %
      % Publication dropdown
      %
      app.PublicationDropDown.Items = app.publications{:,1};
      app.selected_publication = app.publications{1,1};
      %
      % Data set dropdown
      %
      dataset_names = ICP_get_dataset_names(app.selected_publication);
      app.DataSetDropDown.Items = dataset_names;
      app.selected_data_set = dataset_names{1};
      %
      % Citation
      %
      app.datafilename = [app.selected_ice_core '.xlsx'];
      app.sheetname = app.selected_data_set;
      [~,~,~,~,~,app.citation] = readICP(app.datafilename,...
        app.sheetname);
      app.CitationTextArea.Value = app.citation;

      %
      % Map Axes
      %
      app.RobinsonProjectedCRS = projcrs(54030,Authority="ESRI");
      app.MercatorProjectedCRS = projcrs(3395,Authority="EPSG");
      app.OrthographicProjectedCRS = projcrs(102037, Authority="ESRI");
      land = readgeotable("landareas.shp");
      app.map_axes = mapaxes(app.MapAxes,...
        NextPlot="add",...
        Color=[1 1 1]);
      geoplot(app.map_axes,land,...
        FaceColor=[0.85 0.85 0.85],...
        EdgeColor=[0.43 0.43 0.43]);
      app.map_axes.ProjectedCRS = app.MercatorProjectedCRS;
      app.gs=geoscatter(app.map_axes,61.4,-141.7); % Add Bona-Churchill
          app.gs.Marker='pentagram';
          app.gs.MarkerFaceColor='r'; % Vermillion #f4320c
          app.gs.MarkerEdgeColor='k';
          app.gs.SizeData=70;
      
    end

    % Value changed function: IceCoreDropDown
    function IceCoreDropDownValueChanged(app, event)
      app.selected_ice_core = app.IceCoreDropDown.Value;
      ice_core_idx = ismember(app.IceCoreDropDown.Items, app.selected_ice_core);
      app.PublicationDropDown.Items = app.publications{:,find(ice_core_idx)};
      app.selected_publication = app.PublicationDropDown.Value;
      dataset_names = ICP_get_dataset_names(app.selected_publication);
      app.DataSetDropDown.Items = dataset_names;
      app.selected_data_set = dataset_names{1};
      app.datafilename = [app.selected_ice_core '.xlsx'];
      app.sheetname = app.selected_data_set;
      [~,latitude,longitude,~,~,app.citation] = readICP(app.datafilename,...
        app.sheetname);
      app.CitationTextArea.Value = app.citation;
      %
      % Update map
      %
      app.gs.LatitudeData = latitude;
      app.gs.LongitudeData = longitude;
      if any(ismember(app.selected_ice_core, {'bruce-plateau','plateau-remote','siple-station','taylor-dome','taylor-glacier'}))
        app.map_axes.ProjectedCRS = projcrs(3031); % South Pole Projection
      else
        app.map_axes.ProjectedCRS = app.MercatorProjectedCRS;
      end
    end

    % Value changed function: PublicationDropDown
    function PublicationDropDownValueChanged(app, event)
      app.selected_publication = app.PublicationDropDown.Value;
      dataset_names = ICP_get_dataset_names(app.selected_publication);
      app.DataSetDropDown.Items = dataset_names;
      app.selected_data_set = dataset_names{1};
      app.datafilename = [app.selected_ice_core '.xlsx'];
      app.sheetname = app.selected_data_set;
      [~,~,~,~,~,app.citation] = readICP(app.datafilename,...
        app.sheetname);
      app.CitationTextArea.Value = app.citation;
    end

    % Value changed function: DataSetDropDown
    function DataSetDropDownValueChanged(app, event)
      app.selected_data_set = app.DataSetDropDown.Value;
      app.datafilename = [app.selected_ice_core '.xlsx'];
      app.sheetname = app.selected_data_set;
      [~,~,~,~,~,app.citation] = readICP(app.datafilename,...
        app.sheetname);
      app.CitationTextArea.Value = app.citation;
    end

    % Button pushed function: ImportButton
    function ImportButtonPushed(app, event)
      %
      % Enable remaining features
      %
      app.SaveTableButton.Enable = 'on';
      app.PlotButton.Enable = 'on';
      app.ResetButton.Enable = 'on';
      app.SaveFigureButton.Enable = 'on';
      %
      % Import the data in the selected file
      %
      app.data = readICP(app.datafilename, app.sheetname);
      app.DataTable.Data=app.data;
      app.DataTable.ColumnName=app.data.Properties.VariableNames;
      %
      % Populate the parameters dropdowns
      %
      none = 'None';
      dataParams = [none app.data.Properties.VariableNames];
      app.XDataDropDown.Items = dataParams;
      app.YDataDropDown.Items = dataParams;
      %
      % Reset Data Visualization Panel
      %
      app.ResetButtonPushed()
    end

    % Button pushed function: SaveTableButton
    function SaveTableButtonPushed(app, event)
      selfile = questdlg('Choose a file type:', ...
				'Save as...', ...
				'Excel','CSV','Text','Excel');
      if isempty(selfile)
        return
      end
      selpath = uigetdir(pwd,'Where would you like to save the table?');
      if ~selpath
        return
      end

        switch selfile
          case 'Excel'
        				ext = '.xlsx';
					case 'CSV'
        				ext = '.csv';
          case 'Text'
        				ext = '.txt';
        end
        publication_name = app.PublicationDropDown.Value;
				dataset_name = app.DataSetDropDown.Value;
				save_file_as_name = [publication_name,' - ',dataset_name,ext];

				% Save file to selected folder
				writetable(app.DataTable.Data,[selpath '\' save_file_as_name])
        msgbox({'Data saved in selected folder: ', selpath})
    end

    % Button pushed function: PlotButton
    function PlotButtonPushed(app, event)
      %
      % Define colors
      %
      switch app.ColorDropDown.Value
        case 'Black'
          line_color = '#000000';
        case 'Purple'
          line_color = '#9a0eea';
        case 'Blue'
          line_color = '#0343df';
        case 'Green'
          line_color = '#4f9153';
        case 'Yellow'
          line_color = '#faee66';
        case 'Orange'
          line_color = '#f97306';
        case 'Red'
          line_color = '#e50000';
      end
      %
      % Define line styles
      %
      switch app.LineStyleDropDown.Value
        case 'Line'
          line_style = '-';
        case 'Dot'
          line_style = ':';
        case 'Dash'
          line_style = '--';
        case 'Dot-Dash'
          line_style = '-.';
      end
      %
      % Plot data
      %
      if ~strcmp(app.XDataDropDown.Value,'None') ...
          & ~strcmp(app.YDataDropDown.Value,'None')
        app.x = app.data.(app.XDataDropDown.Value);
        app.y = app.data.(app.YDataDropDown.Value);
        line_varargin = {'Color',line_color,...
                         'LineWidth',app.LineWidthSpinner.Value,...
                         'LineStyle',line_style};
        bar_varargin = {'FaceColor',line_color,...
                        'EdgeColor','none'};
        switch app.ScatterShapeDropDown.Value
          case 'Circle'
            marker_type = 'o';
          case 'Diamond'
            marker_type = 'd';
          case 'Square'
            marker_type = 's';
          case 'Triangle'
            marker_type = '^';
        end
        scatter_varargin = {app.MarkerSizeSpinner.Value,...
                            'MarkerFaceColor',line_color,...
                            'Marker',marker_type,...
                            'MarkerEdgeColor','k'};
        if ~isnumeric(app.x) | ~isnumeric(app.y)
          cla(app.DataAxes)
          warndlg('Cannot render plot because x- or y-data are not numeric.')
        else
          switch app.ChartTypeDropDown.Value
            case 'Line'
              app.plt=plot(app.DataAxes, app.x, app.y, line_varargin{:});
            case 'Stairs'
              app.plt=stairs(app.DataAxes, app.x, app.y, line_varargin{:});
            case 'Bar'
              if isequal(length(unique(app.x)), length(app.x))
                % Only attempt to plot a bar chart if there are no
                % x-data duplicates
                app.plt=bar(app.DataAxes, app.x, app.y, bar_varargin{:});
              else
                cla(app.DataAxes)
                warndlg({'Cannot generate bar plot due to duplicate x-axis data.','Try visualizing as a scatter plot.'})
              end
            case 'Scatter'
              app.plt=scatter(app.DataAxes, app.x, app.y, scatter_varargin{:});
          end
          %
          % Update axis labels
          %
          x_label = app.XDataDropDown.Value;
          y_label = app.YDataDropDown.Value;
          xlabel(app.DataAxes, x_label, 'Interpreter', 'none')
          ylabel(app.DataAxes, y_label, 'Interpreter', 'none')
          var_names = app.data.Properties.VariableNames;
          var_units = app.data.Properties.VariableUnits;
          x_idx = ismember(var_names, x_label);
          y_idx = ismember(var_names, y_label);
          xsecondarylabel(app.DataAxes, ['[' var_units{x_idx} ']'])
          ysecondarylabel(app.DataAxes, ['[' var_units{y_idx} ']'])

        end
      %
      % Enable remaining buttons
      %
      app.MovingAverageSlider.Enable = 'on';
      app.LinearRegressionCheckBox.Enable = 'on';
      %
      % Reset moving average slider/regression checkbox/trend checkbox
      %
      app.MovingAverageSlider.Value = 1;
      app.LinearRegressionCheckBox.Value = false;
      app.DisplayTrendCheckBox.Value = false;
      app.DisplayTrendCheckBox.Enable = 'off';

      end
    end

    % Button pushed function: ResetButton
    function ResetButtonPushed(app, event)
      %
      % Axes, data selection, and main customizations
      %
      cla(app.DataAxes)
      app.XDataDropDown.Value = 'None';
      app.YDataDropDown.Value = 'None';
      app.ChartTypeDropDown.Value = 'Line';
      app.ColorDropDown.Value = 'Black';
      app.LineStyleDropDown.Value = 'Line';
      app.LineWidthSpinner.Value = 2;
      app.ScatterShapeDropDown.Value = 'Circle';
      app.MarkerSizeSpinner.Value = 50;
      %
      % More customizations
      %
      app.MovingAverageSlider.Value = 1;
      app.LinearRegressionCheckBox.Value = false;
      app.DisplayTrendCheckBox.Value = false;
      app.DisplayTrendCheckBox.Enable = 'off';
    end

    % Button pushed function: SaveFigureButton
    function SaveFigureButtonPushed(app, event)
			% user_choice = questdlg('Choose a file type:', ...
			% 	'Save as...', ...
			% 	'jpeg','png','tif','jpeg');
			% % Handle response
			% if isempty(user_choice)
			% 	return; % i.e., the user hit 'cancel' so end the loop
			% else
			% 	switch user_choice
			% 		case 'jpeg'
      %   				ext = '.jpg';
			% 		case 'png'
      %   				ext = '.png';
			% 		case 'tif'
      %   				ext = '.tif';
			% 	end
      %   % Define file name
			% 	core_name = app.IceCoreDropDown.Value;
			% 	dataset_name = app.DataSetDropDown.Value;
			% 	save_file_as_name = [core_name,'_',dataset_name,ext];
      % 
			% 	% Save file to current folder
			% 	exportgraphics(app.DataAxes,save_file_as_name,'Resolution',300)
      %   current_dir = pwd;
      %   msgbox({'Image saved in current folder: ', current_dir})
      % end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
      selfile = questdlg('Choose a file type:', ...
				'Save as...', ...
				'jpeg','png','tif','jpeg');
      if isempty(selfile)
        return
      end
      selpath = uigetdir(pwd,'Where would you like to save the figure?');
      if ~selpath
        return
      end

        switch selfile
          case 'jpeg'
        				ext = '.jpg';
					case 'png'
        				ext = '.png';
					case 'tif'
        				ext = '.tif';
        end
        core_name = app.IceCoreDropDown.Value;
				dataset_name = app.DataSetDropDown.Value;
				save_file_as_name = [core_name,'_',dataset_name,ext];

				% Save file to selected folder
				exportgraphics(app.DataAxes,[selpath '\' save_file_as_name],...
          'Resolution',300)
        msgbox({'Data saved in selected folder: ', selpath})
    end

    % Value changed function: LogXScaleCheckBox
    function LogXScaleCheckBoxValueChanged(app, event)
      value = app.LogXScaleCheckBox.Value;
      if value
        set(app.DataAxes,'XScale','log');
      else
        set(app.DataAxes,'XScale','linear');
      end
    end

    % Value changed function: LogYScaleCheckBox
    function LogYScaleCheckBoxValueChanged(app, event)
      value = app.LogYScaleCheckBox.Value;
      if value
        set(app.DataAxes,'YScale','log');
      else
        set(app.DataAxes,'YScale','linear');
      end
    end

    % Value changed function: XGridCheckBox
    function XGridCheckBoxValueChanged(app, event)
      value = app.XGridCheckBox.Value;
      if value
        set(app.DataAxes,'XGrid','on');
      else
        set(app.DataAxes,'XGrid','off');
      end
    end

    % Value changed function: YGridCheckBox
    function YGridCheckBoxValueChanged(app, event)
      value = app.YGridCheckBox.Value;
      if value
        set(app.DataAxes,'YGrid','on');
      else
        set(app.DataAxes,'YGrid','off');
      end
    end

    % Value changed function: XMinorGridCheckBox
    function XMinorGridCheckBoxValueChanged(app, event)
      value = app.XMinorGridCheckBox.Value;
      if value
        set(app.DataAxes,'XMinorGrid','on');
      else
        set(app.DataAxes,'XMinorGrid','off');
      end
    end

    % Value changed function: YMinorGridCheckBox
    function YMinorGridCheckBoxValueChanged(app, event)
      value = app.YMinorGridCheckBox.Value;
      if value
        set(app.DataAxes,'YMinorGrid','on');
      else
        set(app.DataAxes,'YMinorGrid','off');
      end
    end

    % Selection changed function: FontRadioButtonGroup
    function FontRadioButtonGroupSelectionChanged(app, event)
      selected_font = app.FontRadioButtonGroup.SelectedObject;
      switch selected_font.Text
        case 'Serif'
          set(app.DataAxes,'FontName','Times')
        case 'Sans Serif'
          set(app.DataAxes,'FontName','default')
      end
    end

    % Value changed function: FontSizeSpinner
    function FontSizeSpinnerValueChanged(app, event)
      value = app.FontSizeSpinner.Value;
      set(app.DataAxes,'FontSize',value)
    end

    % Value changed function: FontStyleDropDown
    function FontStyleDropDownValueChanged(app, event)
      value = app.FontStyleDropDown.Value;
      switch value
        case 'Normal'
          set(app.DataAxes, 'FontWeight', 'normal', 'FontAngle', 'normal')
        case 'Bold'
          set(app.DataAxes, 'FontWeight', 'bold', 'FontAngle', 'normal')
        case 'Italic'
          set(app.DataAxes, 'FontWeight', 'normal', 'FontAngle', 'italic')
        case 'Bold and Italic'
          set(app.DataAxes, 'FontWeight', 'bold', 'FontAngle', 'italic')
      end
    end

    % Value changed function: MovingAverageSlider
    function MovingAverageSliderValueChanged(app, event)
      moving_average = app.MovingAverageSlider.Value;
      app.y_avg = movmean(app.y, moving_average);
      app.plt.YData = app.y_avg;
    end

    % Value changed function: LinearRegressionCheckBox
    function LinearRegressionCheckBoxValueChanged(app, event)
      lin_reg = app.LinearRegressionCheckBox.Value;
      nan_true = false;
      if any(isnan(app.x)) || any(isnan(app.y))
        nan_true = true;
        % warndlg('Cannot perform linear regression due to NaN values.')
        % app.LinearRegressionCheckBox.Value = false;
        % app.DisplayTrendCheckBox.Value = false;
      end
      if lin_reg
        current_x = app.plt.XData;
        current_y = app.plt.YData;
        if nan_true
          % Remove NaNs related to y-variable
          nan_idx = isnan(current_y);
          current_x = current_x(~nan_idx);
          current_y = current_y(~nan_idx);
          % Remove any remaining NaNs related to x-variable
          nan_idx = isnan(current_x);
          current_x = current_x(~nan_idx);
          current_y = current_y(~nan_idx);
        end
        [~,sort_idx] = sort(current_x);
        app.lin_reg_coef = polyfit(current_x(sort_idx), current_y(sort_idx), 1);
        yFit = polyval(app.lin_reg_coef, current_x(sort_idx));
        hold(app.DataAxes,'on')
          app.lin_reg_line = plot(app.DataAxes, current_x(sort_idx), yFit, '-r');
        hold(app.DataAxes,'off')
        app.DisplayTrendCheckBox.Enable = 'on';
      else
        delete(app.lin_reg_line)
      end
    end

    % Value changed function: DisplayTrendCheckBox
    function DisplayTrendCheckBoxValueChanged(app, event)
      display_trend_on = app.DisplayTrendCheckBox.Value;
      if display_trend_on
        m = app.lin_reg_coef(1);
        b = app.lin_reg_coef(2);
        if abs(b) < 0.0000000001 % 10th decimal place
          b = 0;
        end
        if m==1 && b==0
          s = 'y(x) = x';
        else
          s = sprintf('y(x) = %g*x + %g',m,b);
        end
        hold(app.DataAxes, 'on')
        selected_font = app.FontRadioButtonGroup.SelectedObject;
        switch selected_font.Text
          case 'Serif'
            selected_font = 'Times';
          case 'Sans Serif'
            selected_font = 'default';
        end
          app.trend_text = text(app.DataAxes, 0.05, 0.925, s,...
            'Units','Normalized',...
            'HorizontalAlignment','left',...
            'VerticalAlignment','top',...
            'Interpreter','none',...
            'FontSize',app.FontSizeSpinner.Value,...
            'FontName',selected_font);
        hold(app.DataAxes, 'off')
      else
        delete(app.trend_text)
      end
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.UIFigure = uifigure('Visible', 'off');
      app.UIFigure.Name = 'MATLAB App';
      app.ByrdDataImportToolPanel = uipanel(app.UIFigure);
      app.ByrdDataImportToolPanel.Title = 'Byrd Data Import Tool';
      app.MapAxes = uipanel(app.ByrdDataImportToolPanel);
      app.IceCoreDropDownLabel = uilabel(app.ByrdDataImportToolPanel);
      app.IceCoreDropDownLabel.Text = 'Ice Core';
      app.IceCoreDropDown = uidropdown(app.ByrdDataImportToolPanel);
      app.IceCoreDropDown.ValueChangedFcn = createCallbackFcn(app, @IceCoreDropDownValueChanged, true);
      app.PublicationDropDownLabel = uilabel(app.ByrdDataImportToolPanel);
      app.PublicationDropDownLabel.Text = 'Publication';
      app.PublicationDropDown = uidropdown(app.ByrdDataImportToolPanel);
      app.PublicationDropDown.ValueChangedFcn = createCallbackFcn(app, @PublicationDropDownValueChanged, true);
      app.DataSetDropDownLabel = uilabel(app.ByrdDataImportToolPanel);
      app.DataSetDropDownLabel.Text = 'Data Set';
      app.DataSetDropDown = uidropdown(app.ByrdDataImportToolPanel);
      app.DataSetDropDown.ValueChangedFcn = createCallbackFcn(app, @DataSetDropDownValueChanged, true);
      app.CitationTextAreaLabel = uilabel(app.ByrdDataImportToolPanel);
      app.CitationTextAreaLabel.Text = 'Citation';
      app.CitationTextArea = uitextarea(app.ByrdDataImportToolPanel);
      app.ImportButton = uibutton(app.ByrdDataImportToolPanel, 'push');
      app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
      app.ImportButton.Text = 'Import';
      app.DataTable = uitable(app.ByrdDataImportToolPanel);
      app.DataTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
      app.DataTable.RowName = {};
      app.SaveTableButton = uibutton(app.ByrdDataImportToolPanel, 'push');
      app.SaveTableButton.ButtonPushedFcn = createCallbackFcn(app, @SaveTableButtonPushed, true);
      app.SaveTableButton.Enable = 'off';
      app.SaveTableButton.Text = 'Save as...';
      app.DataTableLabel = uilabel(app.ByrdDataImportToolPanel);
      app.DataTableLabel.Text = 'Data Table';
      app.VisualizationPanel = uipanel(app.UIFigure);
      app.DataAxes = uiaxes(app.VisualizationPanel);
      xlabel(app.DataAxes, 'X-Data')
      ylabel(app.DataAxes, 'Y-Data')
      zlabel(app.DataAxes, 'Z')
      app.ParametersDropDownLabel = uilabel(app.VisualizationPanel);
      app.ParametersDropDownLabel.Text = 'Parameters';
      app.XDataDropDown = uidropdown(app.VisualizationPanel);
      app.XDataDropDown.Items = {'None'};
      app.XDataDropDown.Value = 'None';
      app.YDataDropDown = uidropdown(app.VisualizationPanel);
      app.YDataDropDown.Items = {'None'};
      app.YDataDropDown.Value = 'None';
      app.ChartTypeDropDown = uidropdown(app.VisualizationPanel);
      app.ChartTypeDropDown.Items = {'Line', 'Stairs', 'Bar', 'Scatter'};
      app.ChartTypeDropDown.Value = 'Line';
      app.PlotButton = uibutton(app.VisualizationPanel, 'push');
      app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
      app.PlotButton.Enable = 'off';
      app.PlotButton.Text = 'Plot';
      app.ResetButton = uibutton(app.VisualizationPanel, 'push');
      app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
      app.ResetButton.Enable = 'off';
      app.ResetButton.Text = 'Reset';
      app.SaveFigureButton = uibutton(app.VisualizationPanel, 'push');
      app.SaveFigureButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFigureButtonPushed, true);
      app.SaveFigureButton.Enable = 'off';
      app.SaveFigureButton.Text = 'Save as...';
      app.XDataLabel = uilabel(app.VisualizationPanel);
      app.XDataLabel.Text = 'X-Data';
      app.YDataLabel = uilabel(app.VisualizationPanel);
      app.YDataLabel.Text = 'Y-Data';
      app.ChartTypeLabel = uilabel(app.VisualizationPanel);
      app.ChartTypeLabel.Text = 'Chart Type';
      app.CustomizeDropDownLabel = uilabel(app.VisualizationPanel);
      app.CustomizeDropDownLabel.Text = 'Customize';
      app.ColorDropDown = uidropdown(app.VisualizationPanel);
      app.ColorDropDown.Items = {'Black', 'Purple', 'Blue', 'Green', 'Yellow', 'Orange', 'Red'};
      app.ColorDropDown.Value = 'Black';
      app.LineWidthSpinner = uispinner(app.VisualizationPanel);
      app.LineWidthSpinner.Limits = [0.2 10];
      app.LineWidthSpinner.Value = 2;
      app.LineStyleDropDown = uidropdown(app.VisualizationPanel);
      app.LineStyleDropDown.Items = {'Line', 'Dot', 'Dash', 'Dot-Dash'};
      app.LineStyleDropDown.Value = 'Line';
      app.ColorLabel = uilabel(app.VisualizationPanel);
      app.ColorLabel.Text = 'Color';
      app.LineStyleLabel = uilabel(app.VisualizationPanel);
      app.LineStyleLabel.Text = 'Line Style';
      app.LineWidthLabel = uilabel(app.VisualizationPanel);
      app.LineWidthLabel.Text = 'Line Width';
      app.MoreCustomizationsPanel = uipanel(app.VisualizationPanel);
      app.MoreCustomizationsPanel.Title = 'More Customizations';
      app.FontRadioButtonGroup = uibuttongroup(app.MoreCustomizationsPanel);
      app.FontRadioButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FontRadioButtonGroupSelectionChanged, true);
      app.SansSerifButton = uiradiobutton(app.FontRadioButtonGroup);
      app.SansSerifButton.Text = 'Sans Serif';
      app.SansSerifButton.Value = true;
      app.SerifButton = uiradiobutton(app.FontRadioButtonGroup);
      app.SerifButton.Text = 'Serif';
      app.FontLabel = uilabel(app.FontRadioButtonGroup);
      app.FontLabel.Text = 'Font';
      app.LogXScaleCheckBox = uicheckbox(app.MoreCustomizationsPanel);
      app.LogXScaleCheckBox.ValueChangedFcn = createCallbackFcn(app, @LogXScaleCheckBoxValueChanged, true);
      app.LogXScaleCheckBox.Text = 'Log X-Scale';
      app.LogYScaleCheckBox = uicheckbox(app.MoreCustomizationsPanel);
      app.LogYScaleCheckBox.ValueChangedFcn = createCallbackFcn(app, @LogYScaleCheckBoxValueChanged, true);
      app.LogYScaleCheckBox.Text = 'Log Y-Scale';
      app.XGridCheckBox = uicheckbox(app.MoreCustomizationsPanel);
      app.XGridCheckBox.ValueChangedFcn = createCallbackFcn(app, @XGridCheckBoxValueChanged, true);
      app.XGridCheckBox.Text = 'X Grid';
      app.YGridCheckBox = uicheckbox(app.MoreCustomizationsPanel);
      app.YGridCheckBox.ValueChangedFcn = createCallbackFcn(app, @YGridCheckBoxValueChanged, true);
      app.YGridCheckBox.Text = 'Y Grid';
      app.XMinorGridCheckBox = uicheckbox(app.MoreCustomizationsPanel);
      app.XMinorGridCheckBox.ValueChangedFcn = createCallbackFcn(app, @XMinorGridCheckBoxValueChanged, true);
      app.XMinorGridCheckBox.Text = 'X Minor Grid';
      app.YMinorGridCheckBox = uicheckbox(app.MoreCustomizationsPanel);
      app.YMinorGridCheckBox.ValueChangedFcn = createCallbackFcn(app, @YMinorGridCheckBoxValueChanged, true);
      app.YMinorGridCheckBox.Text = 'Y Minor Grid';
      app.AxesLabel = uilabel(app.MoreCustomizationsPanel);
      app.AxesLabel.Text = 'Axes';
      app.FontSizeSpinnerLabel = uilabel(app.MoreCustomizationsPanel);
      app.FontSizeSpinnerLabel.Text = 'Font Size';
      app.FontSizeSpinner = uispinner(app.MoreCustomizationsPanel);
      app.FontSizeSpinner.Limits = [8 40];
      app.FontSizeSpinner.ValueChangedFcn = createCallbackFcn(app, @FontSizeSpinnerValueChanged, true);
      app.FontSizeSpinner.Value = 14;
      app.FontStyleDropDownLabel = uilabel(app.MoreCustomizationsPanel);
      app.FontStyleDropDownLabel.Text = 'Font Style';
      app.FontStyleDropDown = uidropdown(app.MoreCustomizationsPanel);
      app.FontStyleDropDown.Items = {'Normal', 'Bold', 'Italic', 'Bold and Italic'};
      app.FontStyleDropDown.ValueChangedFcn = createCallbackFcn(app, @FontStyleDropDownValueChanged, true);
      app.FontStyleDropDown.Value = 'Normal';
      app.DataLabel = uilabel(app.MoreCustomizationsPanel);
      app.DataLabel.Text = 'Data';
      app.MovingAverageSliderLabel = uilabel(app.MoreCustomizationsPanel);
      app.MovingAverageSliderLabel.Text = {'Moving'; 'Average'};
      app.MovingAverageSlider = uislider(app.MoreCustomizationsPanel);
      app.MovingAverageSlider.Limits = [1 10];
      app.MovingAverageSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
      app.MovingAverageSlider.ValueChangedFcn = createCallbackFcn(app, @MovingAverageSliderValueChanged, true);
      app.MovingAverageSlider.MinorTicks = [1 1.2 1.4 1.6 1.8 2 2.2 2.4 2.6 2.8 3 3.2 3.4 3.6 3.8 4 4.2 4.4 4.6 4.8 5 5.2 5.4 5.6 5.8 6 6.2 6.4 6.6 6.8 7 7.2 7.4 7.6 7.8 8 8.2 8.4 8.6 8.8 9 9.2 9.4 9.6 9.8 10];
      app.MovingAverageSlider.Enable = 'off';
      app.MovingAverageSlider.Value = 1;
      app.LinearRegressionCheckBox = uicheckbox(app.MoreCustomizationsPanel);
      app.LinearRegressionCheckBox.ValueChangedFcn = createCallbackFcn(app, @LinearRegressionCheckBoxValueChanged, true);
      app.LinearRegressionCheckBox.Enable = 'off';
      app.LinearRegressionCheckBox.Text = 'Linear Regression';
      app.DisplayTrendCheckBox = uicheckbox(app.MoreCustomizationsPanel);
      app.DisplayTrendCheckBox.ValueChangedFcn = createCallbackFcn(app, @DisplayTrendCheckBoxValueChanged, true);
      app.DisplayTrendCheckBox.Enable = 'off';
      app.DisplayTrendCheckBox.Text = 'Display Trend';
      app.DataVisualizationPanelLabel = uilabel(app.VisualizationPanel);
      app.DataVisualizationPanelLabel.Text = 'Data Visualization Panel';
      app.ScatterShapeDropDown = uidropdown(app.VisualizationPanel);
      app.ScatterShapeDropDown.Items = {'Circle', 'Diamond', 'Square', 'Triangle'};
      app.ScatterShapeDropDown.Value = 'Circle';
      app.MarkerTypeLabel = uilabel(app.VisualizationPanel);
      app.MarkerTypeLabel.Text = 'Marker Type';
      app.MarkerSizeSpinner = uispinner(app.VisualizationPanel);
      app.MarkerSizeSpinner.Step = 10;
      app.MarkerSizeSpinner.Limits = [10 100];
      app.MarkerSizeSpinner.Value = 50;
      app.MarkerSizeLabel = uilabel(app.VisualizationPanel);
      app.MarkerSizeLabel.Text = 'Marker Size';
      app.DataSelectionLabel = uilabel(app.VisualizationPanel);
      app.DataSelectionLabel.Text = 'Data Selection';
      % Show the figure after all components are created
      app.UIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = ByrdApp

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.UIFigure)

      % Execute the startup function
      runStartupFcn(app, @startupFcn)

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.UIFigure)
    end
  end
end